classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        ConvolutionTab              matlab.ui.container.Tab
        UploadImageButton           matlab.ui.control.Button
        conv_res_img                matlab.ui.control.Image
        conv_base_img               matlab.ui.control.Image
        FiltersTab                  matlab.ui.container.Tab
        cutofffreqEditField         matlab.ui.control.NumericEditField
        cutofffreqEditFieldLabel    matlab.ui.control.Label
        sigmaEditField              matlab.ui.control.NumericEditField
        sigmaEditFieldLabel         matlab.ui.control.Label
        ButtonGroup                 matlab.ui.container.ButtonGroup
        BHPFButton                  matlab.ui.control.RadioButton
        GHPFButton                  matlab.ui.control.RadioButton
        IHPFButton                  matlab.ui.control.RadioButton
        BLPFButton                  matlab.ui.control.RadioButton
        GLPFButton                  matlab.ui.control.RadioButton
        ILPFButton                  matlab.ui.control.RadioButton
        GaussButton                 matlab.ui.control.RadioButton
        MeanButton                  matlab.ui.control.RadioButton
        UploadFilterImageButton     matlab.ui.control.Button
        filter_res_img              matlab.ui.control.Image
        filter_base_img             matlab.ui.control.Image
        FrequencyFilteringTab       matlab.ui.container.Tab
        factor                      matlab.ui.control.NumericEditField
        factorLabel                 matlab.ui.control.Label
        UploadFrequencyFilterImageButton  matlab.ui.control.Button
        initial_img                 matlab.ui.control.Image
        filtered_img                matlab.ui.control.Image
        NoiseRemovalTab             matlab.ui.container.Tab
        StdDevEditField             matlab.ui.control.NumericEditField
        StdDevEditFieldLabel        matlab.ui.control.Label
        MeanEditField               matlab.ui.control.NumericEditField
        MeanEditFieldLabel          matlab.ui.control.Label
        NoiseDensityEditField       matlab.ui.control.NumericEditField
        NoiseDensityEditFieldLabel  matlab.ui.control.Label
        AlphaEditField              matlab.ui.control.NumericEditField
        AlphaEditFieldLabel         matlab.ui.control.Label
        QEditField                  matlab.ui.control.NumericEditField
        QEditFieldLabel             matlab.ui.control.Label
        FilteringButtonGroup        matlab.ui.container.ButtonGroup
        AlphaTrimmedButton          matlab.ui.control.RadioButton
        MidpointButton              matlab.ui.control.RadioButton
        ContraharmonicButton        matlab.ui.control.RadioButton
        HarmonicButton              matlab.ui.control.RadioButton
        GeomMeanButton              matlab.ui.control.RadioButton
        ArithMeanButton             matlab.ui.control.RadioButton
        MedianButton                matlab.ui.control.RadioButton
        MaxButton                   matlab.ui.control.RadioButton
        MinButton                   matlab.ui.control.RadioButton
        NoiseMethodButtonGroup      matlab.ui.container.ButtonGroup
        GaussButton_2               matlab.ui.control.RadioButton
        SaltPepperButton            matlab.ui.control.RadioButton
        UploadFrequencyFilterImageButton_2  matlab.ui.control.Button
        noiseInitialImg             matlab.ui.control.Image
        noiseResultImg              matlab.ui.control.Image
        PeriodicNoiseRemovalTab     matlab.ui.container.Tab
        Process2dimensionsCheckBox  matlab.ui.control.CheckBox
        DegreesEditField            matlab.ui.control.NumericEditField
        DegreesEditFieldLabel       matlab.ui.control.Label
        FrequencyEditField          matlab.ui.control.NumericEditField
        factorLabel_2               matlab.ui.control.Label
        UploadPeriodicImageButton   matlab.ui.control.Button
        periodicResultImg           matlab.ui.control.Image
        periodicInitialImg          matlab.ui.control.Image
        WienerFilteringTab          matlab.ui.container.Tab
        NSREditField                matlab.ui.control.NumericEditField
        NSREditFieldLabel           matlab.ui.control.Label
        LengthEditField             matlab.ui.control.NumericEditField
        LengthEditFieldLabel        matlab.ui.control.Label
        ThetaEditField              matlab.ui.control.NumericEditField
        ThetaEditFieldLabel         matlab.ui.control.Label
        UploadWienerFilterButton    matlab.ui.control.Button
        wienerResultImg             matlab.ui.control.Image
        wienerInitialImg            matlab.ui.control.Image
    end

    
    properties (Access = private)
        imageFilterMethod uint8 = 0
        inputImageForFilter = 0

        noiseImageMethod = 'saltAndPepper'
        noiseImageFilterMethod = 'min'

        noiseFilteringInitialImage = 0
        periodicNoiseInitialImage = 0
        wienerFilterInitialImage = 0
    end
    
    methods (Access = private)
        
        function updateImageFilter(app)
            if (app.inputImageForFilter == 0)
                return
            end
            switch(app.imageFilterMethod)
                case 1
                    res=filter.mean(app.inputImageForFilter);
                case 2
                    if (app.sigmaEditField.Value<=0)
                        app.sigmaEditField.Value=1;
                    end
                    res=filter.gauss(app.inputImageForFilter,app.sigmaEditField.Value);
                case 3
                    res=filter.ilpf(app.inputImageForFilter,app.sigmaEditField.Value);
                case 4
                    res=filter.glpf(app.inputImageForFilter,app.sigmaEditField.Value);
                case 5
                    res=filter.blpf(app.inputImageForFilter,app.sigmaEditField.Value,app.cutofffreqEditField.Value);
                case 6
                    res=filter.ihpf(app.inputImageForFilter,app.sigmaEditField.Value);
                case 7
                    res=filter.ghpf(app.inputImageForFilter,app.sigmaEditField.Value);
                case 8
                    res=filter.bhpf(app.inputImageForFilter,app.sigmaEditField.Value,app.cutofffreqEditField.Value);
            end

            if size(res, 3) < 3
                res = repmat(res, [1, 1, 3]);
            end

            app.filter_res_img.ImageSource=res;
        end

        function updateNoiseImageFilter(app)
            if app.noiseFilteringInitialImage == 0
                return
            end

            [noise, result] = noiseFiltering(app.noiseFilteringInitialImage, app.NoiseDensityEditField.Value, app.MeanEditField.Value, app.StdDevEditField.Value, app.noiseImageMethod, app.noiseImageFilterMethod, app.QEditField.Value, app.AlphaEditField.Value);

            if size(noise, 3) < 3
                noise = repmat(noise, [1, 1, 3]);
            end

            if size(result, 3) < 3
                result = repmat(result, [1, 1, 3]);
            end
            
            app.noiseInitialImg.ImageSource = noise;
            app.noiseResultImg.ImageSource = result;
        end

        function updatePeriodicImageNoise(app)
            if app.periodicNoiseInitialImage == 0
                return
            end

            [noise, result] = periodicNoiseRemoval(app.periodicNoiseInitialImage, app.DegreesEditField.Value, app.FrequencyEditField.Value, app.Process2dimensionsCheckBox.Value);

            if size(noise, 3) < 3
                noise = repmat(noise, [1, 1, 3]);
            end

            if size(result, 3) < 3
                result = repmat(result, [1, 1, 3]);
            end

            app.periodicInitialImg.ImageSource = noise;
            app.periodicResultImg.ImageSource = result;
        end

        function updateWienerFilter(app)
            if app.wienerFilterInitialImage == 0
                return
            end

            [blurred, result] = wienerFilter(app.wienerFilterInitialImage, app.LengthEditField.Value, app.ThetaEditField.Value, app.NSREditField.Value);

            if size(blurred, 3) < 3
                blurred = repmat(blurred, [1, 1, 3]);
            end
            
            if size(result, 3) < 3
                result = repmat(result, [1, 1, 3]);
            end

            app.wienerInitialImg.ImageSource = blurred;
            app.wienerResultImg.ImageSource = result;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadImageButton
        function UploadImageButtonPushed(app, event)
            [file, path] = uigetfile({'*.jpg;*.jpeg;*.png;*.bmp', 'Image Files (*.jpg, *.jpeg, *.png, *.bmp)'});
            if isequal(file, 0)
                return;
            end
            img = imread(fullfile(path, file));
            % imshow(img,'Parent',app.conv_base_img);
            if size(img, 3) < 3
                img = repmat(img, [1, 1, 3]);
            end
            app.conv_base_img.ImageSource=uint8(img);
            % g=[1 2 1; 2 4 2; 1 2 1]*(1/16);
            % g=[-1 -1 -1; -1 8 -1; -1 -1 -1];
            % g = [0 -1 0; -1 4 -1; 0 -1 0];
            % g = fspecial("gaussian",[7,7],7);
            g = (1/140)*[1 1 2 2 2 1 1;
                1 2 2 4 2 2 1;
                2 2 4 8 4 2 2;
                2 4 8 16 8 4 2;
                2 2 4 8 4 2 2;
                1 2 2 4 2 2 1;
                1 1 2 2 2 1 1;
                ];
            % g = [-1 -1 -1; -1 17 -1; -1 -1 -1];
            res = convolution(im2double(img),double(g));
            if size(res, 3) < 3
                res = repmat(res, [1, 1, 3]);
            end
            app.conv_res_img.ImageSource=res;
        end

        % Button pushed function: UploadFilterImageButton
        function UploadFilterImageButtonPushed(app, event)
            [file, path] = uigetfile({'*.jpg;*.jpeg;*.png;*.bmp', 'Image Files (*.jpg, *.jpeg, *.png, *.bmp)'});
            if isequal(file, 0)
                return;
            end
            img = imread(fullfile(path, file));
            if size(img, 3) < 3
                img = repmat(img, [1, 1, 3]);
            end
            app.inputImageForFilter=img;
            app.filter_base_img.ImageSource=img;
            if (app.imageFilterMethod == 0)
                app.imageFilterMethod = 1;
            end

            updateImageFilter(app);
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            param1=[app.sigmaEditFieldLabel,app.sigmaEditField]
            param2=[app.cutofffreqEditFieldLabel,app.cutofffreqEditField]
            if(strcmp(selectedButton.Text,"Mean"))
                app.imageFilterMethod=1;
                set(param1,"visible","off");
                set(param2,"visible","off");
            elseif(strcmp(selectedButton.Text,"Gauss"))
                app.imageFilterMethod=2;
                app.sigmaEditFieldLabel.Text="sigma";
                set(param1,"visible","on");
                set(param2,"visible","off");
            elseif(strcmp(selectedButton.Text,"ILPF"))
                app.imageFilterMethod=3;
                app.sigmaEditFieldLabel.Text="cutoff freq";
                set(param1,"visible","on");
                set(param2,"visible","off");
            elseif(strcmp(selectedButton.Text,"GLPF"))
                app.imageFilterMethod=4;
                app.sigmaEditFieldLabel.Text="cutoff freq";
                set(param1,"visible","on");
                set(param2,"visible","off");
            elseif(strcmp(selectedButton.Text,"BLPF"))
                app.imageFilterMethod=5;
                app.sigmaEditFieldLabel.Text="cutoff freq";
                set(param1,"visible","on");
                app.cutofffreqEditFieldLabel.Text="n";
                set(param2,"visible","on");
            elseif(strcmp(selectedButton.Text,"IHPF"))
                app.imageFilterMethod=6;
                app.sigmaEditFieldLabel.Text="cutoff freq";
                set(param1,"visible","on");
                set(param2,"visible","off");
            elseif(strcmp(selectedButton.Text,"GHPF"))
                app.imageFilterMethod=7;
                app.sigmaEditFieldLabel.Text="cutoff freq";
                set(param1,"visible","on");
                set(param2,"visible","off");
            else
                app.imageFilterMethod=8;
                app.sigmaEditFieldLabel.Text="cutoff freq";
                set(param1,"visible","on");
                app.cutofffreqEditFieldLabel.Text="n";
                set(param2,"visible","on");
            end

            updateImageFilter(app);
        end

        % Value changed function: sigmaEditField
        function sigmaEditFieldValueChanged(app, event)
            value = app.sigmaEditField.Value;
            updateImageFilter(app);
        end

        % Value changed function: cutofffreqEditField
        function cutofffreqEditFieldValueChanged(app, event)
            value = app.cutofffreqEditField.Value;
            updateImageFilter(app);
        end

        % Button pushed function: UploadFrequencyFilterImageButton
        function UploadFrequencyFilterImageButtonPushed(app, event)
            value = app.factor.Value;
            [file, path] = uigetfile({'*.jpg;*.jpeg;*.png;*.bmp', 'Image Files (*.jpg, *.jpeg, *.png, *.bmp)'});
            if isequal(file, 0)
                return;
            end

            img = imread(fullfile(path, file));
            if size(img, 3) < 3
                img = repmat(img, [1, 1, 3]);
            end

            app.initial_img.ImageSource = img;
            result_img = freqFiltering(img, value);
            if (size(result_img, 3) < 3)
                result_img = repmat(result_img, [1, 1, 3]);
            end
            app.filtered_img.ImageSource = result_img;
        end

        % Value changed function: factor
        function factorValueChanged(app, event)
            if isempty(app.initial_img.ImageSource)
                return;
            end

            value = app.factor.Value;
            app.filtered_img.ImageSource = freqFiltering(app.initial_img.ImageSource, value);
        end

        % Selection changed function: NoiseMethodButtonGroup
        function NoiseMethodButtonGroupSelectionChanged(app, event)
            selectedButton = app.NoiseMethodButtonGroup.SelectedObject;
            saltEditFields = [app.NoiseDensityEditField, app.NoiseDensityEditFieldLabel];
            meanEditFields = [app.MeanEditField, app.MeanEditFieldLabel];
            stdDevEditFields = [app.StdDevEditField, app.StdDevEditFieldLabel];
            switch selectedButton.Text
                case 'Salt & Pepper'
                    app.noiseImageMethod = 'saltAndPepper';
                    set(saltEditFields, "visible", "on");
                    set(meanEditFields, "visible", "off");
                    set(stdDevEditFields, "visible", "off");
                case 'Gauss'
                    app.noiseImageMethod = 'gauss';
                    set(saltEditFields, "visible", "off");
                    set(meanEditFields, "visible", "on");
                    set(stdDevEditFields, "visible", "on");
            end

            updateNoiseImageFilter(app);
        end

        % Selection changed function: FilteringButtonGroup
        function FilteringButtonGroupSelectionChanged(app, event)
            selectedButton = app.FilteringButtonGroup.SelectedObject;
            app.noiseImageFilterMethod = lower(selectedButton.Text);

            switch app.noiseImageFilterMethod
                case 'arith. mean'
                    app.noiseImageFilterMethod = "mean";
                case 'geom. mean'
                    app.noiseImageFilterMethod = "geometric";
            end

            qEditField = [app.QEditField, app.QEditFieldLabel];
            alphaEditField = [app.AlphaEditField, app.AlphaEditFieldLabel];
            switch selectedButton.Text
                case 'Contraharmonic'
                    set(qEditField, "visible", "on");
                    set(alphaEditField, "visible", "off");
                case 'Alpha-Trimmed'
                    set(qEditField, "visible", "off");
                    set(alphaEditField, "visible", "on");
                otherwise
                    set(qEditField, "visible", "off");
                    set(alphaEditField, "visible", "off");
            end

            updateNoiseImageFilter(app);
        end

        % Button pushed function: UploadFrequencyFilterImageButton_2
        function UploadFrequencyFilterImageButton_2Pushed(app, event)
            [file, path] = uigetfile({'*.jpg;*.jpeg;*.png;*.bmp', 'Image Files (*.jpg, *.jpeg, *.png, *.bmp)'});
            if isequal(file, 0)
                return;
            end

            img = imread(fullfile(path, file));
            if size(img, 3) < 3
                img = repmat(img, [1, 1, 3]);
            end

            app.noiseFilteringInitialImage = im2double(img);
            updateNoiseImageFilter(app);
        end

        % Value changed function: AlphaEditField, MeanEditField, 
        % ...and 3 other components
        function NoiseDensityEditFieldValueChanged(app, event)
            updateNoiseImageFilter(app);
        end

        % Button pushed function: UploadPeriodicImageButton
        function UploadPeriodicImageButtonPushed(app, event)
            [file, path] = uigetfile({'*.jpg;*.jpeg;*.png;*.bmp', 'Image Files (*.jpg, *.jpeg, *.png, *.bmp)'});
            if isequal(file, 0)
                return;
            end

            img = imread(fullfile(path, file));
            %if size(img, 3) < 3
            %    img = repmat(img, [1, 1, 3]);
            %end

            app.periodicNoiseInitialImage = im2double(img);
            updatePeriodicImageNoise(app);
        end

        % Value changed function: DegreesEditField, FrequencyEditField, 
        % ...and 1 other component
        function DegreesEditFieldValueChanged(app, event)
            updatePeriodicImageNoise(app);
        end

        % Button pushed function: UploadWienerFilterButton
        function UploadWienerFilterButtonPushed(app, event)
            [file, path] = uigetfile({'*.jpg;*.jpeg;*.png;*.bmp', 'Image Files (*.jpg, *.jpeg, *.png, *.bmp)'});
            if isequal(file, 0)
                return;
            end

            img = imread(fullfile(path, file));
            %if size(img, 3) < 3
            %    img = repmat(img, [1, 1, 3]);
            %end

            app.wienerFilterInitialImage = im2double(img);
            updateWienerFilter(app);
        end

        % Value changed function: LengthEditField, NSREditField, 
        % ...and 1 other component
        function LengthEditFieldValueChanged(app, event)
            updateWienerFilter(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ConvolutionTab = uitab(app.TabGroup);
            app.ConvolutionTab.Title = 'Convolution';
            app.conv_base_img = uiimage(app.ConvolutionTab);
            app.conv_base_img.Tag = 'conv_base';
            app.conv_res_img = uiimage(app.ConvolutionTab);
            app.conv_res_img.Tag = 'conv_base';
            app.UploadImageButton = uibutton(app.ConvolutionTab, 'push');
            app.UploadImageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadImageButtonPushed, true);
            app.UploadImageButton.Text = 'Upload Image';
            app.FiltersTab = uitab(app.TabGroup);
            app.FiltersTab.Title = 'Filters';
            app.filter_base_img = uiimage(app.FiltersTab);
            app.filter_base_img.Tag = 'conv_base';
            app.filter_res_img = uiimage(app.FiltersTab);
            app.filter_res_img.Tag = 'conv_base';
            app.UploadFilterImageButton = uibutton(app.FiltersTab, 'push');
            app.UploadFilterImageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadFilterImageButtonPushed, true);
            app.UploadFilterImageButton.Text = 'Upload Image';
            app.ButtonGroup = uibuttongroup(app.FiltersTab);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = 'Button Group';
            app.MeanButton = uiradiobutton(app.ButtonGroup);
            app.MeanButton.Text = 'Mean';
            app.MeanButton.Value = true;
            app.GaussButton = uiradiobutton(app.ButtonGroup);
            app.GaussButton.Text = 'Gauss';
            app.ILPFButton = uiradiobutton(app.ButtonGroup);
            app.ILPFButton.Text = 'ILPF';
            app.GLPFButton = uiradiobutton(app.ButtonGroup);
            app.GLPFButton.Text = 'GLPF';
            app.BLPFButton = uiradiobutton(app.ButtonGroup);
            app.BLPFButton.Text = 'BLPF';
            app.IHPFButton = uiradiobutton(app.ButtonGroup);
            app.IHPFButton.Text = 'IHPF';
            app.GHPFButton = uiradiobutton(app.ButtonGroup);
            app.GHPFButton.Text = 'GHPF';
            app.BHPFButton = uiradiobutton(app.ButtonGroup);
            app.BHPFButton.Text = 'BHPF';
            app.sigmaEditFieldLabel = uilabel(app.FiltersTab);
            app.sigmaEditFieldLabel.Visible = 'off';
            app.sigmaEditFieldLabel.Text = 'sigma';
            app.sigmaEditField = uieditfield(app.FiltersTab, 'numeric');
            app.sigmaEditField.ValueChangedFcn = createCallbackFcn(app, @sigmaEditFieldValueChanged, true);
            app.sigmaEditField.Visible = 'off';
            app.cutofffreqEditFieldLabel = uilabel(app.FiltersTab);
            app.cutofffreqEditFieldLabel.Visible = 'off';
            app.cutofffreqEditFieldLabel.Text = 'cutoff freq';
            app.cutofffreqEditField = uieditfield(app.FiltersTab, 'numeric');
            app.cutofffreqEditField.ValueChangedFcn = createCallbackFcn(app, @cutofffreqEditFieldValueChanged, true);
            app.cutofffreqEditField.Visible = 'off';
            app.FrequencyFilteringTab = uitab(app.TabGroup);
            app.FrequencyFilteringTab.Title = 'Frequency Filtering';
            app.filtered_img = uiimage(app.FrequencyFilteringTab);
            app.initial_img = uiimage(app.FrequencyFilteringTab);
            app.UploadFrequencyFilterImageButton = uibutton(app.FrequencyFilteringTab, 'push');
            app.UploadFrequencyFilterImageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadFrequencyFilterImageButtonPushed, true);
            app.UploadFrequencyFilterImageButton.Text = 'Upload Image';
            app.factorLabel = uilabel(app.FrequencyFilteringTab);
            app.factorLabel.Text = 'factor';
            app.factor = uieditfield(app.FrequencyFilteringTab, 'numeric');
            app.factor.ValueChangedFcn = createCallbackFcn(app, @factorValueChanged, true);
            app.NoiseRemovalTab = uitab(app.TabGroup);
            app.NoiseRemovalTab.Title = 'Noise Removal';
            app.noiseResultImg = uiimage(app.NoiseRemovalTab);
            app.noiseInitialImg = uiimage(app.NoiseRemovalTab);
            app.UploadFrequencyFilterImageButton_2 = uibutton(app.NoiseRemovalTab, 'push');
            app.UploadFrequencyFilterImageButton_2.ButtonPushedFcn = createCallbackFcn(app, @UploadFrequencyFilterImageButton_2Pushed, true);
            app.UploadFrequencyFilterImageButton_2.Text = 'Upload Image';
            app.NoiseMethodButtonGroup = uibuttongroup(app.NoiseRemovalTab);
            app.NoiseMethodButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @NoiseMethodButtonGroupSelectionChanged, true);
            app.NoiseMethodButtonGroup.Title = 'Noise Method';
            app.SaltPepperButton = uiradiobutton(app.NoiseMethodButtonGroup);
            app.SaltPepperButton.Text = 'Salt & Pepper';
            app.SaltPepperButton.Value = true;
            app.GaussButton_2 = uiradiobutton(app.NoiseMethodButtonGroup);
            app.GaussButton_2.Text = 'Gauss';
            app.FilteringButtonGroup = uibuttongroup(app.NoiseRemovalTab);
            app.FilteringButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FilteringButtonGroupSelectionChanged, true);
            app.FilteringButtonGroup.Title = 'Filtering';
            app.MinButton = uiradiobutton(app.FilteringButtonGroup);
            app.MinButton.Text = 'Min';
            app.MinButton.Value = true;
            app.MaxButton = uiradiobutton(app.FilteringButtonGroup);
            app.MaxButton.Text = 'Max';
            app.MedianButton = uiradiobutton(app.FilteringButtonGroup);
            app.MedianButton.Text = 'Median';
            app.ArithMeanButton = uiradiobutton(app.FilteringButtonGroup);
            app.ArithMeanButton.Text = 'Arith. Mean';
            app.GeomMeanButton = uiradiobutton(app.FilteringButtonGroup);
            app.GeomMeanButton.Text = 'Geom. Mean';
            app.HarmonicButton = uiradiobutton(app.FilteringButtonGroup);
            app.HarmonicButton.Text = 'Harmonic';
            app.ContraharmonicButton = uiradiobutton(app.FilteringButtonGroup);
            app.ContraharmonicButton.Text = 'Contraharmonic';
            app.MidpointButton = uiradiobutton(app.FilteringButtonGroup);
            app.MidpointButton.Text = 'Midpoint';
            app.AlphaTrimmedButton = uiradiobutton(app.FilteringButtonGroup);
            app.AlphaTrimmedButton.Text = 'Alpha-Trimmed';
            app.QEditFieldLabel = uilabel(app.NoiseRemovalTab);
            app.QEditFieldLabel.Visible = 'off';
            app.QEditFieldLabel.Text = 'Q';
            app.QEditField = uieditfield(app.NoiseRemovalTab, 'numeric');
            app.QEditField.ValueChangedFcn = createCallbackFcn(app, @NoiseDensityEditFieldValueChanged, true);
            app.QEditField.Visible = 'off';
            app.AlphaEditFieldLabel = uilabel(app.NoiseRemovalTab);
            app.AlphaEditFieldLabel.Visible = 'off';
            app.AlphaEditFieldLabel.Text = 'Alpha';
            app.AlphaEditField = uieditfield(app.NoiseRemovalTab, 'numeric');
            app.AlphaEditField.ValueChangedFcn = createCallbackFcn(app, @NoiseDensityEditFieldValueChanged, true);
            app.AlphaEditField.Visible = 'off';
            app.NoiseDensityEditFieldLabel = uilabel(app.NoiseRemovalTab);
            app.NoiseDensityEditFieldLabel.Text = 'Noise Density';
            app.NoiseDensityEditField = uieditfield(app.NoiseRemovalTab, 'numeric');
            app.NoiseDensityEditField.ValueChangedFcn = createCallbackFcn(app, @NoiseDensityEditFieldValueChanged, true);
            app.MeanEditFieldLabel = uilabel(app.NoiseRemovalTab);
            app.MeanEditFieldLabel.Visible = 'off';
            app.MeanEditFieldLabel.Text = 'Mean';
            app.MeanEditField = uieditfield(app.NoiseRemovalTab, 'numeric');
            app.MeanEditField.ValueChangedFcn = createCallbackFcn(app, @NoiseDensityEditFieldValueChanged, true);
            app.MeanEditField.Visible = 'off';
            app.StdDevEditFieldLabel = uilabel(app.NoiseRemovalTab);
            app.StdDevEditFieldLabel.Visible = 'off';
            app.StdDevEditFieldLabel.Text = 'Std. Dev';
            app.StdDevEditField = uieditfield(app.NoiseRemovalTab, 'numeric');
            app.StdDevEditField.ValueChangedFcn = createCallbackFcn(app, @NoiseDensityEditFieldValueChanged, true);
            app.StdDevEditField.Visible = 'off';
            app.PeriodicNoiseRemovalTab = uitab(app.TabGroup);
            app.PeriodicNoiseRemovalTab.Title = 'Periodic Noise Removal';
            app.periodicInitialImg = uiimage(app.PeriodicNoiseRemovalTab);
            app.periodicResultImg = uiimage(app.PeriodicNoiseRemovalTab);
            app.UploadPeriodicImageButton = uibutton(app.PeriodicNoiseRemovalTab, 'push');
            app.UploadPeriodicImageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadPeriodicImageButtonPushed, true);
            app.UploadPeriodicImageButton.Text = 'Upload Image';
            app.factorLabel_2 = uilabel(app.PeriodicNoiseRemovalTab);
            app.factorLabel_2.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.PeriodicNoiseRemovalTab, 'numeric');
            app.FrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @DegreesEditFieldValueChanged, true);
            app.DegreesEditFieldLabel = uilabel(app.PeriodicNoiseRemovalTab);
            app.DegreesEditFieldLabel.Text = 'Degrees';
            app.DegreesEditField = uieditfield(app.PeriodicNoiseRemovalTab, 'numeric');
            app.DegreesEditField.ValueChangedFcn = createCallbackFcn(app, @DegreesEditFieldValueChanged, true);
            app.Process2dimensionsCheckBox = uicheckbox(app.PeriodicNoiseRemovalTab);
            app.Process2dimensionsCheckBox.ValueChangedFcn = createCallbackFcn(app, @DegreesEditFieldValueChanged, true);
            app.Process2dimensionsCheckBox.Text = 'Process 2 dimensions';
            app.WienerFilteringTab = uitab(app.TabGroup);
            app.WienerFilteringTab.Title = 'Wiener Filtering';
            app.wienerInitialImg = uiimage(app.WienerFilteringTab);
            app.wienerResultImg = uiimage(app.WienerFilteringTab);
            app.UploadWienerFilterButton = uibutton(app.WienerFilteringTab, 'push');
            app.UploadWienerFilterButton.ButtonPushedFcn = createCallbackFcn(app, @UploadWienerFilterButtonPushed, true);
            app.UploadWienerFilterButton.Text = 'Upload Image';
            app.ThetaEditFieldLabel = uilabel(app.WienerFilteringTab);
            app.ThetaEditFieldLabel.Text = 'Theta';
            app.ThetaEditField = uieditfield(app.WienerFilteringTab, 'numeric');
            app.ThetaEditField.ValueChangedFcn = createCallbackFcn(app, @LengthEditFieldValueChanged, true);
            app.LengthEditFieldLabel = uilabel(app.WienerFilteringTab);
            app.LengthEditFieldLabel.Text = 'Length';
            app.LengthEditField = uieditfield(app.WienerFilteringTab, 'numeric');
            app.LengthEditField.ValueChangedFcn = createCallbackFcn(app, @LengthEditFieldValueChanged, true);
            app.NSREditFieldLabel = uilabel(app.WienerFilteringTab);
            app.NSREditFieldLabel.Text = 'NSR';
            app.NSREditField = uieditfield(app.WienerFilteringTab, 'numeric');
            app.NSREditField.ValueChangedFcn = createCallbackFcn(app, @LengthEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end